classdef TTL2Table_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SaveButton             matlab.ui.control.Button
        OutputTablePanel       matlab.ui.container.Panel
        OutputTable            matlab.ui.control.Table
        DataKeyPanel           matlab.ui.container.Panel
        DataKeyTable           matlab.ui.control.Table
        CombineSignalsPanel    matlab.ui.container.Panel
        ResetButton            matlab.ui.control.Button
        MergeButton            matlab.ui.control.Button
        MergedNameEditField    matlab.ui.control.EditField
        MergeSignalsListBox    matlab.ui.control.ListBox
        DataKeyPathLabel       matlab.ui.control.Label
        TTLPathLabel           matlab.ui.control.Label
        SelectDataKeyButton    matlab.ui.control.Button
        SelectTTLFolderButton  matlab.ui.control.Button
    end


    properties (Access = private)
        data_key % Table mapping variable names to numeric values in the TTL signal
        data_key_loaded = false % Keep track of whether data key has been loaded
        states_vect % Stores data from "states.npy"
        samples_vect % Stores data from "sample_numbers.npy"
        npy_loaded = false % Tracks whether states and samples have been loaded
        merged_signals_table = table()% Stores newly-created variables after merging other signals
    end

    methods (Access = private)

        % Update the signals being analyzed in the multiselect box for
        % combining signals
        function updateMergeBox(app)
            data_key_temp = app.DataKeyTable.DisplayData;
            var_names = data_key_temp.VariableName(data_key_temp.Analyze);
            app.MergeSignalsListBox.Items = var_names;
        end

        % Combine the data key and the TTL data to find the signal
        % onsets/offsets
        function updateOutputTable(app)
            % Isolate selected variables
            data_key_temp = app.DataKeyTable.DisplayData;
            data_key_temp = data_key_temp(data_key_temp.Analyze,1:end-1);
            varNames = data_key_temp.VariableName;
            % Combute onset/offset indices for each variable
            [start_ind_cell,end_ind_cell] = deal(cell(length(varNames),1));  
            for i = 1:length(varNames)
                data_key_i = data_key_temp{i,"Key"};
                [start_ind_cell{i},end_ind_cell{i}] = getSignalInds(app,data_key_i);
                % Ensure that these are row vectors
                if size(start_ind_cell{i},1) > 1
                    start_ind_cell{i} = start_ind_cell{i}';
                end
                if size(end_ind_cell{i},1) > 1
                    end_ind_cell{i} = end_ind_cell{i}';
                end
            end
            
            output_table = [data_key_temp,...
                cell2table([start_ind_cell,end_ind_cell],"VariableNames",{'StartInds','EndInds'})];
            output_table.Properties.RowNames = output_table.VariableName;
            output_table.VariableName = [];
            
            % If we have any merged variables, append those to the end of
            % the output table. Merged variables are stored in a separate
            % table. 
            if ~isempty(app.merged_signals_table)
                output_table = [output_table ; app.merged_signals_table];
            end
            
            app.OutputTable.Data = output_table;
            app.OutputTable.ColumnName = output_table.Properties.VariableNames;
            app.OutputTable.RowName = output_table.Properties.RowNames;
        end
        
        function [start_inds,end_inds] = getSignalInds(app,signal_key)
            start_inds = app.samples_vect(app.states_vect == signal_key);
            end_inds = app.samples_vect(app.states_vect == -signal_key);
            if size(start_inds,2) == 1
                start_inds = start_inds';
            end
            if size(end_inds,2) == 1
                end_inds = end_inds';
            end
        end
        
        function [merged_start,merged_end] = mergeSignals(app,signals_to_merge)
            % Get pairwise combinations of all signal names that we want to
            % merge
            signal_name_pairs = nchoosek(signals_to_merge,2);
            % Iterate through signal pairs and check if they overlap
            n_signal_pairs = size(signal_name_pairs,1);
            for i = 1:n_signal_pairs
                curr_signal_names = signal_name_pairs(i,:);
                signal_a = curr_signal_names(1);
                signal_b = curr_signal_names(2);
                signal_key_a = app.data_key.Key(app.data_key.VariableName == signal_a);
                signal_key_b = app.data_key.Key(app.data_key.VariableName == signal_b);
                [start_inds_a,end_inds_a] = getSignalInds(app,signal_key_a);
                [start_inds_b,end_inds_b] = getSignalInds(app,signal_key_b);
                a_start_end = [start_inds_a ; end_inds_a]';
                b_start_end = [start_inds_b ; end_inds_b]';
                is_overlapping = signalsOverlapping(app,a_start_end,b_start_end,signal_a,signal_b);
                if is_overlapping
                    error("Signals are overlapping.")
                end
            end
            % Merge all signal starts and signal ends into single vectors
            % and sort them to create the start/end indices of the merged
            % signal.
            n_signals = length(signals_to_merge);
            [start_cell,end_cell] = deal(cell(1,length(n_signals)));
            for i = 1:n_signals
                signal_key_i = app.data_key.Key(app.data_key.VariableName == signals_to_merge(i));
                [start_cell{i},end_cell{i}] = getSignalInds(app,signal_key_i);
            end
            merged_start = sort(horzcat(start_cell{:}));
            merged_end = sort(horzcat(end_cell{:}));
        end
        
        % Check if two signals overlap, by having start/stop times that
        % coincide.
        function is_overlapping = signalsOverlapping(app,a_start_end,b_start_end,a_var_name,b_var_name)
            for a_ind = 1:size(a_start_end,1)
                range_a = a_start_end(a_ind,:);
                is_overlapping = test_overlap_vector(app,range_a,b_start_end);
                if is_overlapping
                    errormsg = "Signals "+a_var_name+" and "+b_var_name+...
                        " are overlapping and cannot be merged";
                    uiwait(errordlg(errormsg));
                    return
                end
            end
            is_overlapping = false;
        end
        % Compare a single signal start/end interval to all start/end times
        % of a second signal.
        function is_overlapping = test_overlap_vector(app,range_a,range_mat_b)
            for ind_b = 1:size(range_mat_b,1)
                range_b = range_mat_b(ind_b,:);
                is_overlapping_ind_b = test_single_overlap(app,range_a,range_b);
                if is_overlapping_ind_b
                    is_overlapping = true;
                    return
                end
                is_overlapping = false;
            end

        end
        % Compare two start/end intervals from different signals to see if
        % they overlap
        function is_overlapping = test_single_overlap(~,range_a,range_b)
            if all(range_b < range_a(1))
                is_overlapping = false;
            elseif all(range_b > range_a(2))
                is_overlapping = false;
            else
                is_overlapping = true;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectDataKeyButton
        function SelectDataKeyButtonPushed(app, event)
            % Navigate to the data key file and load it in with readtable
            % Populate the data key navigator of the App with the data
            [file,path] = uigetfile("*.txt;*.csv;*.xls;*.xlsx","Select data key file:");
            if ~isequal(file,0)
                filepath = fullfile(path,file);
                app.DataKeyPathLabel.Text = filepath;
                app.data_key = readtable(filepath,...
                    'ReadVariableNames',true,...
                    'TextType','string');
                % Append logical values, which will be made editable
                app.data_key.Analyze = true(height(app.data_key),1);
                app.DataKeyTable.Data = app.data_key;
                app.DataKeyTable.ColumnName = app.data_key.Properties.VariableNames;
                isEditable = false(1,width(app.DataKeyTable.Data));
                isEditable(end) = true;
                app.DataKeyTable.ColumnEditable = isEditable;
                app.data_key_loaded = true;
                app.DataKeyPanel.Enable='on';
                updateMergeBox(app)
                if app.npy_loaded                    
                    updateOutputTable(app)
                    app.CombineSignalsPanel.Enable = 'on';
                    app.OutputTablePanel.Enable = 'on';
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            outpath_default = fileparts(app.DataKeyPathLabel.Text);
            name_default = 'BehaviorDataTable.mat';
            BehaviorDataTable = app.OutputTable.DisplayData;
            uisave('BehaviorDataTable',fullfile(outpath_default,name_default))
        end

        % Cell edit callback: DataKeyTable
        function DataKeyTableCellEdit(app, event)
            updateMergeBox(app)
            if app.npy_loaded
                updateOutputTable(app)
            end
        end

        % Button pushed function: SelectTTLFolderButton
        function SelectTTLFolderButtonPushed(app, event)
            path = uigetdir("Select folder containing TTL .npy files:");
            if ~isequal(path,0)
                app.TTLPathLabel.Text = path;
                app.samples_vect = readNPY(fullfile(path,"sample_numbers.npy"));
                app.states_vect = readNPY(fullfile(path,"states.npy"));
                app.npy_loaded = true;
                if app.data_key_loaded
                    updateOutputTable(app)
                    updateMergeBox(app)
                    app.OutputTablePanel.Enable = 'on';
                    app.DataKeyPanel.Enable = 'on';
                    app.CombineSignalsPanel.Enable = 'on';
                end
            end
        end

        % Value changing function: MergedNameEditField
        function MergedNameEditFieldValueChanging(app, event)
            changingValue = event.Value;
            % value = app.MergedNameEditField.Value;
            if isvarname(changingValue) || isempty(changingValue)
                app.MergedNameEditField.FontColor = 'k';
                app.MergedNameEditField.BackgroundColor = 'w';
            else
                app.MergedNameEditField.FontColor = 'r';
                app.MergedNameEditField.BackgroundColor = [1 0.8 0.8];
            end
        end

        % Button pushed function: MergeButton
        function MergeButtonPushed(app, event)
            % Check that the new variable name is valid
            new_name = app.MergedNameEditField.Value;
            if ~isvarname(new_name)
                uiwait(errordlg('Invalid name. New signal name must be a valid MATLAB variable name.'))
                return
            end
            % Check that multiple values are selected
            signals_to_merge = app.MergeSignalsListBox.Value;
            if length(signals_to_merge) < 2
                uiwait(errordlg( ...
                    'Cannot merge a single signal. Select multiple signals to merge (ctrl/shift/cmd + click).'))
                return
            end
            % Merge signals
            % - This will also check if the signals are overlapping and
            % throw an error if they are
            signals_to_merge = string(signals_to_merge);
            [merged_start,merged_end] = mergeSignals(app,signals_to_merge);
            % Add merged signal to a table. This will also create a new
            % table if one doesn't exist
            app.merged_signals_table{new_name,'Key'} = nan;
            app.merged_signals_table{new_name,'StartInds'} = {merged_start};
            app.merged_signals_table{new_name,'EndInds'} = {merged_end};
            
            updateOutputTable(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.merged_signals_table = table();
            updateOutputTable(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectTTLFolderButton = uibutton(app.UIFigure, 'push');
            app.SelectTTLFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTTLFolderButtonPushed, true);
            app.SelectTTLFolderButton.Text = 'Select TTL Folder';
            app.SelectDataKeyButton = uibutton(app.UIFigure, 'push');
            app.SelectDataKeyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataKeyButtonPushed, true);
            app.SelectDataKeyButton.Text = 'Select Data Key';
            app.TTLPathLabel = uilabel(app.UIFigure);
            app.TTLPathLabel.Text = 'TTL Path';
            app.DataKeyPathLabel = uilabel(app.UIFigure);
            app.DataKeyPathLabel.Text = 'Data Key Path';
            app.CombineSignalsPanel = uipanel(app.UIFigure);
            app.CombineSignalsPanel.Enable = 'off';
            app.CombineSignalsPanel.Title = 'Combine Signals';
            app.MergeSignalsListBox = uilistbox(app.CombineSignalsPanel);
            app.MergeSignalsListBox.Multiselect = 'on';
            app.MergeSignalsListBox.Value = {'Item 1'};
            app.MergedNameEditField = uieditfield(app.CombineSignalsPanel, 'text');
            app.MergedNameEditField.ValueChangingFcn = createCallbackFcn(app, @MergedNameEditFieldValueChanging, true);
            app.MergedNameEditField.Placeholder = 'New_Name';
            app.MergeButton = uibutton(app.CombineSignalsPanel, 'push');
            app.MergeButton.ButtonPushedFcn = createCallbackFcn(app, @MergeButtonPushed, true);
            app.MergeButton.Text = 'Merge';
            app.ResetButton = uibutton(app.CombineSignalsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DataKeyPanel = uipanel(app.UIFigure);
            app.DataKeyPanel.Enable = 'off';
            app.DataKeyPanel.Title = 'Data Key';
            app.DataKeyTable = uitable(app.DataKeyPanel);
            app.DataKeyTable.ColumnName = {'VariableName'; 'Key'; 'Analyze'};
            app.DataKeyTable.RowName = {};
            app.DataKeyTable.ColumnSortable = true;
            app.DataKeyTable.ColumnEditable = [false false true];
            app.DataKeyTable.CellEditCallback = createCallbackFcn(app, @DataKeyTableCellEdit, true);
            app.OutputTablePanel = uipanel(app.UIFigure);
            app.OutputTablePanel.Enable = 'off';
            app.OutputTablePanel.Title = 'Output Table';
            app.OutputTable = uitable(app.OutputTablePanel);
            app.OutputTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OutputTable.RowName = {};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTL2Table_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end