classdef MotionEnergyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ProcessVideoButton         matlab.ui.control.Button
        NavigatePanel              matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        GoToFrameEditField         matlab.ui.control.NumericEditField
        GoToFrameEditFieldLabel    matlab.ui.control.Label
        ForwardFrameButton         matlab.ui.control.Button
        BackFrameButton            matlab.ui.control.Button
        RegionsofInterestROIPanel  matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        ClearASROIButton           matlab.ui.control.Button
        AlignmentSignalButton      matlab.ui.control.Button
        ClearMEROIButton           matlab.ui.control.Button
        MotionEnergyButton         matlab.ui.control.Button
        VideoPathLabel             matlab.ui.control.Label
        LoadVideoButton            matlab.ui.control.Button
        VideoFrameAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        videoPath % Full file path to video file
        VR % VideoReader object for selected video
        currFrameInd % Current Frame Index
        alignmentROI % ROI object for alignment signal
        alignmentROIDrawn = false % Boolean indicating if the ROI has been drawn
        alignmentROI_holder = struct % Temporarily stores ROI information between frames
        motionROI % ROI object for computing motion energy
        motionROIDrawn = false % Boolean indicating if the ROI has been drawn
        motionROI_holder = struct % Temporarily stores ROI information between frames

    end
    
    methods (Access = private)
        
        function DrawFrame(app)
            % Store ROI objects so we don't overwrite them:
            if app.alignmentROIDrawn
                alignmentROI_temp = copyobj(app.alignmentROI,app.alignmentROI_holder);
            end
            if app.motionROIDrawn
                motionROI_temp = copyobj(app.motionROI,app.motionROI_holder);
            end

            currFrame = read(app.VR,app.currFrameInd);
            imshow(currFrame,'Parent',app.VideoFrameAxes);
            titleString = "Frame " + string(app.currFrameInd)+" of "+string(app.VR.NumFrames);
            title(app.VideoFrameAxes,titleString);

            if app.alignmentROIDrawn
                app.alignmentROI = copyobj(alignmentROI_temp,app.VideoFrameAxes);
            end
            if app.motionROIDrawn
                app.motionROI = copyobj(motionROI_temp,app.VideoFrameAxes);
            end
        end
        
        % For creating a summary figure after calculating data

        function drawSummaryFigure(app,outputData)
            figHandle = figure;
            sp1 = subplot(2,1,1);
            sp2 = subplot(2,1,2);
            % If more than 20K frames, only plot the first 20K
            if length(outputData.MotionEnergy) > 20000
                plot(sp1,outputData.MotionEnergy(1:20000),'Color','b');
                plot(sp2,outputData.AlignmentSignal(1:20000),'Color','r');
            else
                plot(sp1,outputData.MotionEnergy,'Color','b');
                plot(sp2,outputData.AlignmentSignal,'Color','r');
            end
            title(sp1,'Motion Energy');
            ylabel(sp1,'\mu(\Deltapx)')
            title(sp2,'Alignment Signal');
            ylabel(sp2,'\mu(px)');
            xlabel(sp2,'Frame');
            linkaxes([sp1,sp2],'x');
            
            if length(outputData.MotionEnergy) > 20000
                sgtitle(figHandle,app.VR.Name+" (first 20K frames)",'Interpreter','none');
            else
                sgtitle(figHandle,app.VR.Name,'Interpreter','none');
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)
            [file,path] = uigetfile({'*.mp4;*.avi'},'Select a video file');
            if isequal(file,0)
                return
            end
            app.videoPath = fullfile(path,file);
            app.VR = VideoReader(app.videoPath);
            app.currFrameInd = 1;
            app.GoToFrameEditField.Limits = [1 , app.VR.NumFrames];
            app.VideoPathLabel.Text = app.videoPath;
            % Delete any preexisting ROIs
            if app.alignmentROIDrawn
                delete(app.alignmentROI);
                app.alignmentROI = [];
                app.alignmentROIDrawn = false;
            end
            if app.motionROIDrawn
                delete(app.motionROI);
                app.motionROI = [];
                app.motionROIDrawn = false;
            end
            % Enable the rest of the app:
            set([app.NavigatePanel,...
                 app.RegionsofInterestROIPanel,...
                 app.ProcessVideoButton],'Enable','on');

            DrawFrame(app)
        end

        % Button pushed function: BackFrameButton
        function BackFrameButtonPushed(app, event)
            if app.currFrameInd > 1
                app.currFrameInd = app.currFrameInd - 1;
            end
            DrawFrame(app);
        end

        % Button pushed function: ForwardFrameButton
        function ForwardFrameButtonPushed(app, event)
            if app.currFrameInd < app.VR.NumFrames
                app.currFrameInd = app.currFrameInd + 1;
            end
            DrawFrame(app);
        end

        % Value changed function: GoToFrameEditField
        function GoToFrameEditFieldValueChanged(app, event)
            app.currFrameInd = app.GoToFrameEditField.Value;
            DrawFrame(app);
        end

        % Button pushed function: AlignmentSignalButton
        function AlignmentSignalButtonPushed(app, event)
            if app.alignmentROIDrawn == true
                delete(app.alignmentROI);
            end
            app.alignmentROI = drawpolygon("Parent",app.VideoFrameAxes,"Color","red");
            app.alignmentROIDrawn = true;
        end

        % Button pushed function: ClearASROIButton
        function ClearASROIButtonPushed(app, event)
            if app.alignmentROIDrawn == true
                delete(app.alignmentROI);
            end
            app.alignmentROIDrawn = false;
            app.alignmentROI = [];
        end

        % Button pushed function: MotionEnergyButton
        function MotionEnergyButtonPushed(app, event)
            if app.motionROIDrawn == true
                delete(app.motionROI);
            end
            app.motionROI = drawpolygon("Parent",app.VideoFrameAxes,"Color","blue");
            app.motionROIDrawn = true;
        end

        % Button pushed function: ClearMEROIButton
        function ClearMEROIButtonPushed(app, event)
            if app.motionROIDrawn == true
                delete(app.motionROI);
            end
            app.motionROIDrawn = false;
            app.motionROI = [];
        end

        % Button pushed function: ProcessVideoButton
        function ProcessVideoButtonPushed(app, event)
            set([app.LoadVideoButton,...
                 app.NavigatePanel,...
                 app.RegionsofInterestROIPanel,...
                 app.ProcessVideoButton],'Enable','off')
            dataStructOut = motionEnergyWholeVideo(app.VR,app.motionROI,app.alignmentROI,...
                'UseProgressBar',true);
            % Save output to same folder as pupil video:
            if ~isempty(dataStructOut)
                [~,videoName] = fileparts(app.VR.name);
                outputName = videoName+"_motionEnergy.mat";
                outputFilePath = fullfile(app.VR.Path,outputName);
                save(outputFilePath,'-struct','dataStructOut')


            end
            set([app.LoadVideoButton,...
                 app.NavigatePanel,...
                 app.RegionsofInterestROIPanel,...
                 app.ProcessVideoButton],'Enable','on')
            drawSummaryFigure(app,dataStructOut);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VideoFrameAxes = uiaxes(app.UIFigure);
            title(app.VideoFrameAxes, 'VideoFrame')
            zlabel(app.VideoFrameAxes, 'Z')
            app.VideoFrameAxes.XTick = [];
            app.VideoFrameAxes.YTick = [];
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Load Video';
            app.VideoPathLabel = uilabel(app.UIFigure);
            app.VideoPathLabel.Text = 'VideoPath';
            app.RegionsofInterestROIPanel = uipanel(app.UIFigure);
            app.RegionsofInterestROIPanel.Enable = 'off';
            app.RegionsofInterestROIPanel.Title = 'Regions of Interest (ROI)';
            app.GridLayout = uigridlayout(app.RegionsofInterestROIPanel);
            app.MotionEnergyButton = uibutton(app.GridLayout, 'push');
            app.MotionEnergyButton.ButtonPushedFcn = createCallbackFcn(app, @MotionEnergyButtonPushed, true);
            app.MotionEnergyButton.Text = 'Motion Energy';
            app.ClearMEROIButton = uibutton(app.GridLayout, 'push');
            app.ClearMEROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMEROIButtonPushed, true);
            app.ClearMEROIButton.Text = 'Clear';
            app.AlignmentSignalButton = uibutton(app.GridLayout, 'push');
            app.AlignmentSignalButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentSignalButtonPushed, true);
            app.AlignmentSignalButton.Text = 'Alignment Signal';
            app.ClearASROIButton = uibutton(app.GridLayout, 'push');
            app.ClearASROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearASROIButtonPushed, true);
            app.ClearASROIButton.Text = 'Clear';
            app.NavigatePanel = uipanel(app.UIFigure);
            app.NavigatePanel.Enable = 'off';
            app.NavigatePanel.Title = 'Navigate';
            app.GridLayout2 = uigridlayout(app.NavigatePanel);
            app.BackFrameButton = uibutton(app.GridLayout2, 'push');
            app.BackFrameButton.ButtonPushedFcn = createCallbackFcn(app, @BackFrameButtonPushed, true);
            app.BackFrameButton.Text = '<';
            app.ForwardFrameButton = uibutton(app.GridLayout2, 'push');
            app.ForwardFrameButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardFrameButtonPushed, true);
            app.ForwardFrameButton.Text = '>';
            app.GoToFrameEditFieldLabel = uilabel(app.GridLayout2);
            app.GoToFrameEditFieldLabel.Text = 'Go To Frame:';
            app.GoToFrameEditField = uieditfield(app.GridLayout2, 'numeric');
            app.GoToFrameEditField.Limits = [1 Inf];
            app.GoToFrameEditField.RoundFractionalValues = 'on';
            app.GoToFrameEditField.ValueChangedFcn = createCallbackFcn(app, @GoToFrameEditFieldValueChanged, true);
            app.GoToFrameEditField.Value = 1;
            app.ProcessVideoButton = uibutton(app.UIFigure, 'push');
            app.ProcessVideoButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessVideoButtonPushed, true);
            app.ProcessVideoButton.Enable = 'off';
            app.ProcessVideoButton.Text = 'Process Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionEnergyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end